classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ExitButton        matlab.ui.control.Button
        GetStartedButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cameraOn % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.cameraOn = false;
            close all;
        end

        % Button pushed function: GetStartedButton
        function GetStartedButtonPushed(app, event)
            cam = webcam('EOS Webcam Utility');
            app.cameraOn = true;
            while app.cameraOn
                frame = snapshot(cam);
                im = image(app.UIAxes, flip(frame, 1));            
                axis(app.UIAxes, 'image');
                preview(cam, im); 
                pause(0.03);
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.GetStartedButton = uibutton(app.UIFigure, 'push');
            app.GetStartedButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.GetStartedButton.Text = 'Get Started';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dummy Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end